classdef P21_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StateLabel           matlab.ui.control.Label
        ExportResultsButton  matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        Image                matlab.ui.control.Image
        StartButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %TD add log for stopping the current recording, Sam C
            pyrunfile("PythonScripts/StopScan.py")
            app.StateLabel.Text = "Stopped";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %TD add log and function to start the recording Process, Sam C
            pyrunfile("PythonScripts/StartScan.py");
            app.StateLabel.Text = "Running";
          
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            %TD add a Log for the button press and implement functionality
            %for the user to save the resulting image to a folder location,
            %Sam C
            pyrunfile("PythonScripts/Export.py")
            app.StateLabel.Text = "Exporting Image";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Image = uiimage(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ExportResultsButton = uibutton(app.UIFigure, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'State';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P21_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end